classdef proiect2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NrdepasiEditField            matlab.ui.control.NumericEditField
        NrdepasiEditFieldLabel       matlab.ui.control.Label
        RadacinapatrataaproximataesteEditField  matlab.ui.control.NumericEditField
        RadacinapatrataaproximataesteEditFieldLabel  matlab.ui.control.Label
        CalculeazaButton             matlab.ui.control.Button
        IntroducetieroareaEditField  matlab.ui.control.NumericEditField
        IntroducetieroareaEditFieldLabel  matlab.ui.control.Label
        IntroducetinumarulEditField  matlab.ui.control.NumericEditField
        IntroducetinumarulEditFieldLabel  matlab.ui.control.Label
        AproximarearadaciniipatrateLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        epsilon = 0;
        nr = 0;
        y = 0;
        nr_pasi;
    end
    
    methods (Access = private)
        
        function aproxRad(app)
            app.nr_pasi = 0;
            eroare = app.epsilon+1;
            x(2) = 1;

        if app.nr<0
            error('Numarul trebuie sa fie pozitiv')
        end

        while eroare >= app.epsilon

            x(1) = x(2);
            x(2) = (x(1)/(2*app.nr))*(3*app.nr-x(1)^2);
            eroare = abs(sqrt(app.nr)- x(2));
            app.nr_pasi = app.nr_pasi+1;
        end
            app.y = x(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IntroducetinumarulEditField
        function IntroducetinumarulEditFieldValueChanged(app, event)
            app.nr = app.IntroducetinumarulEditField.Value;
            
        end

        % Value changed function: IntroducetieroareaEditField
        function IntroducetieroareaEditFieldValueChanged(app, event)
            app.epsilon = app.IntroducetieroareaEditField.Value;
            
        end

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            aproxRad(app);
            app.RadacinapatrataaproximataesteEditField.Value = app.y;
            app.NrdepasiEditField.Value = app.nr_pasi;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AproximarearadaciniipatrateLabel = uilabel(app.UIFigure);
            app.AproximarearadaciniipatrateLabel.Text = 'Aproximarea radacinii patrate';
            app.IntroducetinumarulEditFieldLabel = uilabel(app.UIFigure);
            app.IntroducetinumarulEditFieldLabel.Text = 'Introduceti numarul';
            app.IntroducetinumarulEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntroducetinumarulEditField.ValueChangedFcn = createCallbackFcn(app, @IntroducetinumarulEditFieldValueChanged, true);
            app.IntroducetieroareaEditFieldLabel = uilabel(app.UIFigure);
            app.IntroducetieroareaEditFieldLabel.Text = 'Introduceti eroarea ε';
            app.IntroducetieroareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntroducetieroareaEditField.ValueChangedFcn = createCallbackFcn(app, @IntroducetieroareaEditFieldValueChanged, true);
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Text = 'Calculeaza';
            app.RadacinapatrataaproximataesteEditFieldLabel = uilabel(app.UIFigure);
            app.RadacinapatrataaproximataesteEditFieldLabel.Text = 'Radacina patrata aproximata este:';
            app.RadacinapatrataaproximataesteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadacinapatrataaproximataesteEditField.ValueDisplayFormat = '%.6f';
            app.RadacinapatrataaproximataesteEditField.Editable = 'off';
            app.NrdepasiEditFieldLabel = uilabel(app.UIFigure);
            app.NrdepasiEditFieldLabel.Text = 'Nr. de pasi';
            app.NrdepasiEditField = uieditfield(app.UIFigure, 'numeric');
            app.NrdepasiEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proiect2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end